classdef Bitplane < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BitPlaneLabel           matlab.ui.control.Label
        BrowseInputImageButton  matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_4                matlab.ui.control.UIAxes
        UIAxes_5                matlab.ui.control.UIAxes
        UIAxes_6                matlab.ui.control.UIAxes
        UIAxes_7                matlab.ui.control.UIAxes
        UIAxes_8                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseInputImageButton
        function browse(app, event)
            [filename,filepath] = uigetfile({'*.jpg';'*.png'});
            fullName = fullfile(filepath, filename);
            imageFile = imread(fullName);
           imagegray = rgb2gray(imageFile);
           B = bitget(imagegray, 1);
           imshow(logical(B),'Parent', app.UIAxes);
           B = bitget(imagegray, 2);
           imshow(logical(B),'Parent', app.UIAxes_2);
           B = bitget(imagegray, 3);
           imshow(logical(B),'Parent', app.UIAxes_3);
           B = bitget(imagegray, 4);
           imshow(logical(B),'Parent', app.UIAxes_4);
           B = bitget(imagegray, 5);
           imshow(logical(B),'Parent', app.UIAxes_5);
           B = bitget(imagegray, 6);
           imshow(logical(B),'Parent', app.UIAxes_6);
           B = bitget(imagegray, 7);
           imshow(logical(B),'Parent', app.UIAxes_7);
           B = bitget(imagegray, 8);
           imshow(logical(B),'Parent', app.UIAxes_8);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BitPlaneLabel = uilabel(app.UIFigure);
            app.BitPlaneLabel.Text = 'Bit Plane';
            app.BrowseInputImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @browse, true);
            app.BrowseInputImageButton.Text = 'Browse Input Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '1')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '3')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '2')
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '5')
            app.UIAxes_4.Visible = 'off';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '4')
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '7')
            app.UIAxes_6.Visible = 'off';
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, '8')
            app.UIAxes_7.Visible = 'off';
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, '6')
            app.UIAxes_8.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bitplane

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end