classdef Log < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LogTransformLabel            matlab.ui.control.Label
        BrowseImageButton            matlab.ui.control.Button
        SaveImageButton              matlab.ui.control.Button
        EnterlogvalueEditFieldLabel  matlab.ui.control.Label
        EnterlogvalueEditField       matlab.ui.control.NumericEditField
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function transform(app, event)
            global image1
            global l
            l = app.EnterlogvalueEditField.Value;
            if l==0 
                msg = 'You must enter a number in the log value box';
                errordlg(msg);
                return;
            end
            try
            [filename,filepath] = uigetfile({'*.jpg';'*.png'});
            fullName = fullfile(filepath, filename);
            imageFile = imread(fullName);
            imageF = rgb2gray(imageFile);
            image = im2double(imageF);
            image1 = l*log(1+image);
            imshow(imageF, 'Parent', app.UIAxes_4)
            imshow(image1, 'Parent', app.UIAxes_3)
            app.BrowseImageButton.Enable = 'off';
            catch
                msg3 ='Please select an Image';
                errordlg(msg3);
            end
        end

        % Button pushed function: SaveImageButton
        function save(app, event)
            global image1
            folder = uigetdir();
            if ~isempty(image1)
                imwrite (image1, fullfile(folder, 'log.bmp'));
            end
        end

        % Callback function
        function exit(app, event)
          
        end

        % Close request function: UIFigure
        function close(app, event)
             clear global;
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @close, true);
            app.LogTransformLabel = uilabel(app.UIFigure);
            app.LogTransformLabel.Text = 'Log Transform';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @transform, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImageButton.Text = 'Save Image';
            app.EnterlogvalueEditFieldLabel = uilabel(app.UIFigure);
            app.EnterlogvalueEditFieldLabel.Text = 'Enter log value';
            app.EnterlogvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Transformed 2*log')
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Original')
            app.UIAxes_4.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Log

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end