classdef arithmatic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ArithmaticLabel         matlab.ui.control.Label
        BrowseImage2Button      matlab.ui.control.Button
        BrowseImage1Button_2    matlab.ui.control.StateButton
        Label                   matlab.ui.control.Label
        ChooseanoperationLabel  matlab.ui.control.Label
        addCheckBox             matlab.ui.control.CheckBox
        subtractCheckBox        matlab.ui.control.CheckBox
        multiplyCheckBox        matlab.ui.control.CheckBox
        divideCheckBox          matlab.ui.control.CheckBox
        SaveImagesButton        matlab.ui.control.Button
        ExitButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function operation(app, event)
            
        end

        % Value changed function: BrowseImage1Button_2
        function image1(app, event)
            global image1
            [filename,filepath] = uigetfile({'*.jpg';'*.png'; '*.bmp'});
            fullName = fullfile(filepath, filename);
            image1 = imread(fullName);

        end

        % Button pushed function: BrowseImage2Button
        function image2(app, event)
            global image2
            [filename,filepath] = uigetfile({'*.jpg';'*.png'; '*.bmp'});
            fullName = fullfile(filepath, filename);
            image2 = imread(fullName);
        end

        % Value changed function: addCheckBox
        function add(app, event)
            global image1
            global image2
            global a
            imshow(image1,'Parent', app.UIAxes);
            imshow(image2, 'Parent',app.UIAxes_2);
            a = image1 + image2;
            imshow(a, 'Parent', app.UIAxes_3);
        end

        % Value changed function: subtractCheckBox
        function subtract(app, event)
            global image1
            global s
            imshow(image1,'Parent', app.UIAxes)
            s = image1 - 15;
            imshow(s, 'Parent', app.UIAxes_3);
        end

        % Value changed function: multiplyCheckBox
        function multiply(app, event)
            global image1;
            global m
            imshow(image1,'Parent', app.UIAxes);
            m = image1 * 1.2;
            imshow(m, 'Parent', app.UIAxes_3);
        end

        % Value changed function: divideCheckBox
        function divide(app, event)
            global image1
            global d
            imshow(image1,'Parent', app.UIAxes);
            d = image1/ 2;
            imshow(d, 'Parent', app.UIAxes_3);
            
        end

        % Button pushed function: SaveImagesButton
        function save(app, event)
            global a
            global s
            global m
            global d
            folder = uigetdir();
             if ~isempty(s)
            imwrite (s, fullfile(folder, 'subtract.bmp'));
             end
              if ~isempty(m)
            imwrite (m, fullfile(folder, 'multiply.bmp'));
              end
             if ~isempty(d)
            imwrite (d, fullfile(folder, 'divide.bmp'));
             end
              if ~isempty(a)
            imwrite (a, fullfile(folder, 'add.bmp'));
              end
              clear global
        end

        % Button pushed function: ExitButton
        function exit(app, event)
            clear global;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArithmaticLabel = uilabel(app.UIFigure);
            app.ArithmaticLabel.Text = 'Arithmatic';
            app.BrowseImage2Button = uibutton(app.UIFigure, 'push');
            app.BrowseImage2Button.ButtonPushedFcn = createCallbackFcn(app, @image2, true);
            app.BrowseImage2Button.Text = 'Browse Image 2';
            app.BrowseImage1Button_2 = uibutton(app.UIFigure, 'state');
            app.BrowseImage1Button_2.ValueChangedFcn = createCallbackFcn(app, @image1, true);
            app.BrowseImage1Button_2.Text = 'Browse Image 1';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1. Browse for Image 1 and Image 2.';
            app.ChooseanoperationLabel = uilabel(app.UIFigure);
            app.ChooseanoperationLabel.Text = '2. Choose an operation.';
            app.addCheckBox = uicheckbox(app.UIFigure);
            app.addCheckBox.ValueChangedFcn = createCallbackFcn(app, @add, true);
            app.addCheckBox.Text = 'add';
            app.subtractCheckBox = uicheckbox(app.UIFigure);
            app.subtractCheckBox.ValueChangedFcn = createCallbackFcn(app, @subtract, true);
            app.subtractCheckBox.Text = 'subtract';
            app.multiplyCheckBox = uicheckbox(app.UIFigure);
            app.multiplyCheckBox.ValueChangedFcn = createCallbackFcn(app, @multiply, true);
            app.multiplyCheckBox.Text = 'multiply';
            app.divideCheckBox = uicheckbox(app.UIFigure);
            app.divideCheckBox.ValueChangedFcn = createCallbackFcn(app, @divide, true);
            app.divideCheckBox.Text = 'divide';
            app.SaveImagesButton = uibutton(app.UIFigure, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @exit, true);
            app.ExitButton.Text = 'Exit';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image 1')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Image 2')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Resuting Image')
            app.UIAxes_3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arithmatic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end