classdef linear < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LinearandPiecewiseLinearTransformationsLabel  matlab.ui.control.Label
        SearchforInputImageButton   matlab.ui.control.Button
        SaveImageButton             matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        RotateCheckBox              matlab.ui.control.CheckBox
        ContrastStretchingCheckBox  matlab.ui.control.CheckBox
        DilateCheckBox              matlab.ui.control.CheckBox
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchforInputImageButton
        function search(app, event)
             global image
            [filename,filepath] = uigetfile({'*.bmp; *.png; *.jpeg'});
            fullName = fullfile(filepath, filename);
            image = imread(fullName);
        end

        % Value changed function: RotateCheckBox
        function rotat(app, event)
            global image
            global r
            r = imrotate(image, 5);
            imshow(image,'Parent', app.UIAxes);
            imshow(r, 'Parent',app.UIAxes2);
        end

        % Value changed function: ContrastStretchingCheckBox
        function cstretching(app, event)
           global image
            global c
            c = imadjust(image, stretchlim(image, [0.05 0.95]),[]);
            imshow(image,'Parent', app.UIAxes);
            imshow(c, 'Parent',app.UIAxes2);
            
        end

        % Value changed function: DilateCheckBox
        function dil(app, event)
            global image
            global d
            SE = strel('square',50);
            d = imdilate(image,SE);
            imshow(image,'Parent', app.UIAxes);
            imshow(d, 'Parent',app.UIAxes2);
            
            
        end

        % Button pushed function: SaveImageButton
        function save(app, event)
            global d
            global r
            global c
            folder = uigetdir();
             if ~isempty(d)
            imwrite (d, fullfile(folder, 'dilate.bmp'));
             end
             if ~isempty(r)
            imwrite (r, fullfile(folder, 'rotate.bmp'));
             end
             if ~isempty(c)
            imwrite (c, fullfile(folder, 'contrastStretch.bmp'));
             end
            clear global;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LinearandPiecewiseLinearTransformationsLabel = uilabel(app.UIFigure);
            app.LinearandPiecewiseLinearTransformationsLabel.Text = 'Linear and Piece-wise Linear Transformations';
            app.SearchforInputImageButton = uibutton(app.UIFigure, 'push');
            app.SearchforInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @search, true);
            app.SearchforInputImageButton.Text = 'Search for Input Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.Text = 'Exit';
            app.RotateCheckBox = uicheckbox(app.UIFigure);
            app.RotateCheckBox.ValueChangedFcn = createCallbackFcn(app, @rotat, true);
            app.RotateCheckBox.Text = 'Rotate';
            app.ContrastStretchingCheckBox = uicheckbox(app.UIFigure);
            app.ContrastStretchingCheckBox.ValueChangedFcn = createCallbackFcn(app, @cstretching, true);
            app.ContrastStretchingCheckBox.Text = 'Contrast Stretching';
            app.DilateCheckBox = uicheckbox(app.UIFigure);
            app.DilateCheckBox.ValueChangedFcn = createCallbackFcn(app, @dil, true);
            app.DilateCheckBox.Text = 'Dilate';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Result')
            app.UIAxes2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linear

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end