classdef powerlaw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PowerLawGammaLabel             matlab.ui.control.Label
        SubmitandBrowseImageButton     matlab.ui.control.Button
        EntercvalueEditFieldLabel      matlab.ui.control.Label
        EntercvalueEditField           matlab.ui.control.NumericEditField
        EntergammavalueEditFieldLabel  matlab.ui.control.Label
        EntergammavalueEditField       matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitandBrowseImageButton
        function browse(app, event)
            [filename,filepath] = uigetfile({'*.jpg';'*.png'});
            fullName = fullfile(filepath, filename);
            imageFile = imread(fullName);
            imageF = rgb2gray(imageFile);
            image = im2double(imageF);
            c = app.EntercvalueEditField.Value;
            g = app.EntergammavalueEditField.Value;
            if c~=0 && g ~=0 
                 image1 = c*(image.^g);
                 imshow(imageF, 'Parent', app.UIAxes);
                imshow(image1, 'Parent', app.UIAxes_2);
            else
                msg = 'You must enter a number in the gamma and c value box';
                error(msg);
            end
        end

        % Value changed function: EntercvalueEditField
        function cValue(app, event)
 
        end

        % Callback function
        function gama(app, event)

        end

        % Callback function
        function EntergammavalueEditFieldValueChanged(app, event)
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PowerLawGammaLabel = uilabel(app.UIFigure);
            app.PowerLawGammaLabel.Text = 'Power Law / Gamma';
            app.SubmitandBrowseImageButton = uibutton(app.UIFigure, 'push');
            app.SubmitandBrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @browse, true);
            app.SubmitandBrowseImageButton.Text = 'Submit and Browse Image';
            app.EntercvalueEditFieldLabel = uilabel(app.UIFigure);
            app.EntercvalueEditFieldLabel.Text = 'Enter c value';
            app.EntercvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntercvalueEditField.ValueChangedFcn = createCallbackFcn(app, @cValue, true);
            app.EntergammavalueEditFieldLabel = uilabel(app.UIFigure);
            app.EntergammavalueEditFieldLabel.Text = 'Enter gamma value';
            app.EntergammavalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original ')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Transform')
            app.UIAxes_2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = powerlaw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end